classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NoFileLoadedLabel             matlab.ui.control.Label
        PredictTimingViolationButton  matlab.ui.control.Button
        LoadRTLFileButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        FilePath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadRTLFileButton
        function LoadRTLFileButtonPushed(app, event)
           

            [file, path] = uigetfile({'*.v;*.sv', 'Verilog Files (*.v, *.sv)'}, 'Select RTL File');
            disp(['Stored File Path: ', app.FilePath]);
    
    % If user selects a file, save its path
    if file ~= 0
        app.FilePath = fullfile(path, file);
        app.NoFileLoadedLabel.Text = "File Loaded: " + file;
    else
        app.NoFileLoadedLabel.Text = "No File Loaded";
    end
        end

        % Button pushed function: PredictTimingViolationButton
        function PredictTimingViolationButtonPushed(app, event)
  % Check if an RTL file has been loaded
if isempty(app.FilePath)
    uialert(app.UIFigure, 'Please load an RTL file first!', 'Error');
    return;
end

try
    % Debugging: Display the RTL file path
    disp(['Attempting to read file at: ', app.FilePath]);

    % Read the loaded RTL file
    rtl_code = fileread(app.FilePath);
    disp(rtl_code);  % Debugging: Check if file content is read correctly

    % Define model file path
    modelFilePath = 'models/trained_net.mat';

    % Check if the file exists before loading
    if ~isfile(modelFilePath)
        % Try letting the user select the correct file
        [file, path] = uigetfile('*.mat', 'Select Model File');
        if isequal(file, 0)
            uialert(app.UIFigure, 'Error: No model file selected.', 'File Error');
            return;
        end
        modelFilePath = fullfile(path, file);
    end

    % Debugging: Display the model file path
    disp(['Loading model from: ', modelFilePath]);

    % Load the trained ML model
    modelData = load(modelFilePath);

    % Check if the expected variable exists
    if isfield(modelData, 'trained_net')
        trained_net = modelData.trained_net;
    elseif isfield(modelData, 'net')  % Alternative field name
        trained_net = modelData.net;
    else
        uialert(app.UIFigure, 'Error: No valid model found in MAT file.', 'Model Load Error');
        return;
    end

    % Debugging: Show the fields present in the loaded .mat file
    disp('Variables inside the MAT file:');
    disp(fieldnames(modelData));

    % Extract features from the RTL code
    features = extract_features(rtl_code);

    % Ensure features are in the correct format for prediction
    if isempty(features)
        uialert(app.UIFigure, 'Feature extraction failed. Check RTL file format.', 'Error');
        return;
    end

    % Make a prediction using the trained model
    prediction = predict(trained_net, features);

    % Display the prediction result
    if prediction == 1
        uialert(app.UIFigure, 'Timing Violation Detected!', 'Result');
    else
        uialert(app.UIFigure, 'No Timing Violation Found.', 'Result');
    end

catch ME
    % Display an error message if something goes wrong
    uialert(app.UIFigure, ['An error occurred: ', ME.message], 'Processing Error');
    disp(['Error: ', ME.message]); % Debugging output
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadRTLFileButton = uibutton(app.UIFigure, 'push');
            app.LoadRTLFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRTLFileButtonPushed, true);
            app.LoadRTLFileButton.Text = 'Load RTL File';
            app.PredictTimingViolationButton = uibutton(app.UIFigure, 'push');
            app.PredictTimingViolationButton.ButtonPushedFcn = createCallbackFcn(app, @PredictTimingViolationButtonPushed, true);
            app.PredictTimingViolationButton.Text = 'Predict Timing Violation';
            app.NoFileLoadedLabel = uilabel(app.UIFigure);
            app.NoFileLoadedLabel.Text = 'No File Loaded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end